classdef GraficaServos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ResetButton   matlab.ui.control.Button
        pararButton   matlab.ui.control.Button
        inicioButton  matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim=[0 30];
            app.UIAxes.YLim=[0 30];
            app.s=serialport("COM3",115200,"Timeout",5);
        end

        % Button pushed function: inicioButton
        function inicioButtonPushed(app, event)
            global parar
            parar=0;
            while 1
                if parar==1
                    break;
                else
                    angulos=double(split(readline(app.s)));
                    x=22*cos(angulos(1))+18*cos(angulos(2)+angulos(1));
                    y=22*sin(angulos(1))+18*sin(angulos(2)+angulos(1));
                    plot(app.UIAxes,x,y);
                    app.UIAxes.XLim=[0 30];
                    app.UIAxes.YLim=[0 30];
                end
            end
        end

        % Button pushed function: pararButton
        function pararButtonPushed(app, event)
            global parar
            parar=1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,"reset");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.inicioButton = uibutton(app.UIFigure, 'push');
            app.inicioButton.ButtonPushedFcn = createCallbackFcn(app, @inicioButtonPushed, true);
            app.inicioButton.Text = 'inicio';
            app.pararButton = uibutton(app.UIFigure, 'push');
            app.pararButton.ButtonPushedFcn = createCallbackFcn(app, @pararButtonPushed, true);
            app.pararButton.Text = 'parar';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficaServos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end